classdef CI_LIF_Splitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CILIFSPlitterUIFigure   matlab.ui.Figure
        ConvertRGBImagestoQTIFFCheckBox  matlab.ui.control.CheckBox
        ProgressBar             wt.ProgressBar
        AboutButton             matlab.ui.control.Button
        RButton                 matlab.ui.control.Button
        LogBox                  matlab.ui.control.ListBox
        CloseSelectedFileLeica  matlab.ui.control.Button
        SelectFileLeica         matlab.ui.control.Button
        CButtonLIFSplit         matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        Tree                    matlab.ui.container.Tree
        Node                    matlab.ui.container.TreeNode
        StatusPanel             matlab.ui.container.Panel
        Panel                   matlab.ui.container.Panel
    end

    
    properties (Access = public)
        Version='1.1'

        host=' '                     % Hostname (for debug purpose)
        
        filetype=''                 % lif or xlef
        filepath=''                 % filepath of lif/xlef file
        filename=''                 % lif/xlef file name
        filebasename=''             % lif/xlef file name without extension
        file=''                     % lif/xlef fullfile name (including filepath)
        
        Nodes  matlab.ui.container.TreeNode  % Nodes for treeview
        
        isCancelled=false           % Process is canceled (Cancel button pressed)
    end
    
    properties (Access = private)
        timeSpend=0         % Accumelated time spend
        starttime           % All start time
        
        InterfaceObj        % All interface objects (that are on)
    end
    
    methods (Access = private)

        function appDisable(app)
            app.isCancelled=false;
            app.InterfaceObj=findobj(app.CILIFSPlitterUIFigure,'Enable','on');
            set(app.InterfaceObj,'Enable','off');
            app.LogBox.Enable='on';
            app.CancelButton.Enable='on';
            app.StatusPanel.Enable='on';
            app.Panel.Enable='on';
            app.RButton.Enable='on';
        end
        
        function appEnable(app)
            app.isCancelled=false;
            set(app.InterfaceObj,'Enable','on');
        end
        
        function ReadLeicaLIF(app)
            app.Tree.Enable='off';
            %app.Node.delete
            fileID = fopen(app.file,'r','n','UTF-8');
            testvalue=fread(fileID,1,'int32');
            if testvalue~=112; return; end 
            XMLContentLenght=fread(fileID,1,'int32');
            testvalue=fread(fileID,1,'uint8');
            if testvalue~=42; return; end 
            testvalue=fread(fileID,1,'int32');
            XMLObjDescriptionUTF16=reshape(fread(fileID,2*testvalue,'*char'),1,testvalue*2);
            XMLObjDescription=cfUnicode2ascii(XMLObjDescriptionUTF16);
            XMLObjDescription=regexprep(XMLObjDescription,'</',[newline '</']);
            xmlElements=cfExtractElementContents(XMLObjDescription);
            
            %Read memory blocks
            lifinfo=struct;
            tel=0;
            while ~feof(fileID)
                tel=tel+1;
                testvalue=fread(fileID,1,'int32');
                if feof(fileID); break; end
                if testvalue ~=112; return; end
                BinContentLenght=fread(fileID,1,'int32');
                testvalue=fread(fileID,1,'uint8');
                if testvalue~=42; return; end 
                MemorySize=fread(fileID,1,'int64');
                testvalue=fread(fileID,1,'uint8');
                if testvalue~=42; return; end 
                testvalue=fread(fileID,1,'int32');
                sBlockID=cfUnicode2ascii(reshape(fread(fileID,2*testvalue,'*char'),1,testvalue*2));
                lifinfo(tel).BlockID=sBlockID;
                lifinfo(tel).MemorySize=MemorySize;
                lifinfo(tel).Position=ftell(fileID);
                if MemorySize>0; fseek(fileID,MemorySize,0); end
            end
            fclose(fileID);

            setLog(app,'Opening LIF-File');
            s=xml2structstring(['<?xml version="1.0" encoding="ISO-8859-1"?>' XMLObjDescription]);
            app.Node = uitreenode(app.Tree,'Text',app.filename,'NodeData',fileparts(app.file), 'Icon','LAS-X.png','Tag','File');
            setLog(app,'LAS-X LIF File Opened');
            cnode=0;
            elements=numel(s.LMSDataContainerHeader.Element.Children.Element);
            setLog(app,[num2str(elements) ' element nodes found']);
            for k = 1:elements
                cnode=cnode+1;
                if elements==1
                    thiselement=s.LMSDataContainerHeader.Element.Children.Element;
                else
                    thiselement=s.LMSDataContainerHeader.Element.Children.Element{k};
                end
                MemoryBlockID=thiselement.Memory.Attributes.MemoryBlockID;
                lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                MemorySize=str2double(thiselement.Memory.Attributes.Size);
                name=thiselement.Attributes.Name;
                lifinfo(lifinfoindex).filetype=app.filetype;
                lifinfo(lifinfoindex).LIFFile=app.file;
                lifinfo(lifinfoindex).name=name;
                ix=cellfun(@(x) contains(x, MemoryBlockID), xmlElements);
                ix=cfKeepLastOne(ix);
                lifinfo(lifinfoindex).xmlElement=xmlElements{ix};
                if MemorySize==0 && ~strcmpi(name,'iomanagerconfiguation')
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',name,'Icon','folder.png','Tag','Folder');
                    elements1=numel(thiselement.Children.Element);
                    cnode1=cnode;
                    for k1 = 1:elements1
                        cnode=cnode+1;
                        if elements1==1
                            thiselement1 = thiselement.Children.Element;
                        else
                            thiselement1 = thiselement.Children.Element{k1};
                        end
                        MemoryBlockID=thiselement1.Memory.Attributes.MemoryBlockID;
                        lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                        MemorySize=str2double(thiselement1.Memory.Attributes.Size);
                        name1=thiselement1.Attributes.Name;
                        lifinfo(lifinfoindex).filetype=app.filetype;
                        lifinfo(lifinfoindex).LIFFile=app.file;
                        lifinfo(lifinfoindex).name=name1;
                        ix=cellfun(@(x) contains(x, MemoryBlockID), xmlElements);
                        ix=cfKeepLastOne(ix);
                        lifinfo(lifinfoindex).xmlElement=xmlElements{ix};
                        if MemorySize==0 && ~strcmpi(name,'iomanagerconfiguation')
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',name1,'Icon','folder.png','Tag','Folder');
                            elements2=numel(thiselement1.Children.Element);
                            cnode2=cnode;
                            for k2 = 1:elements2
                                cnode=cnode+1;
                                if elements2==1
                                    thiselement2 = thiselement1.Children.Element;
                                else
                                    thiselement2 = thiselement1.Children.Element{k2};
                                end
                                MemoryBlockID=thiselement2.Memory.Attributes.MemoryBlockID;
                                lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                                MemorySize=str2double(thiselement2.Memory.Attributes.Size);
                                name2=thiselement2.Attributes.Name;
                                lifinfo(lifinfoindex).name=name2;
                                ix=cellfun(@(x) contains(x, MemoryBlockID), xmlElements);
                                ix=cfKeepLastOne(ix);
                                lifinfo(lifinfoindex).xmlElement=xmlElements{ix};
                                if MemorySize==0 && ~strcmpi(name,'iomanagerconfiguation')
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',name2,'Icon','folder.png','Tag','Folder');
                                    elements3=numel(thiselement2.Children.Element);
                                    cnode3=cnode;
                                    for k3 = 1:elements3
                                        cnode=cnode+1;
                                        if elements3==1
                                            thiselement3 = thiselement2.Children.Element;
                                        else
                                            thiselement3 = thiselement2.Children.Element{k3};
                                        end
                                        MemoryBlockID=thiselement3.Memory.Attributes.MemoryBlockID;
                                        lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                                        MemorySize=str2double(thiselement3.Memory.Attributes.Size);
                                        name3=thiselement3.Attributes.Name;
                                        lifinfo(lifinfoindex).name=name3;
                                        ix=cellfun(@(x) contains(x, MemoryBlockID), xmlElements);
                                        ix=cfKeepLastOne(ix);
                                        lifinfo(lifinfoindex).xmlElement=xmlElements{ix};
                                        if MemorySize==0 && ~strcmpi(name,'iomanagerconfiguation')
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',name3,'Icon','folder.png','Tag','Folder');
                                        elseif ~strcmpi(name3,'iomanagerconfiguation') && ~strcmpi(name3,'environmentalgraph')
                                            lifinfo(lifinfoindex).filetype=app.filetype;
                                            lifinfo(lifinfoindex).LIFFile=app.file;
                                            lifinfo(lifinfoindex).name=name3;
                                            if isfield(thiselement3.Data,'Image') 
                                                lifinfo(lifinfoindex).datatype='Image';
                                                lifinfo(lifinfoindex).Image=thiselement3.Data.Image;
                                            elseif isfield(thiselement3.Data,'GISTEventList')
                                                lifinfo(lifinfoindex).datatype='Eventlist';
                                                lifinfo(lifinfoindex).GISTEventList=thiselement3.Data.GISTEventList;
                                            else
                                                lifinfo(lifinfoindex).datatype='unknown';
                                            end
                                            lifinfo(lifinfoindex).Parent=[app.Nodes(cnode3).Parent.Parent.Text '_' app.Nodes(cnode3).Parent.Text '_' app.Nodes(cnode3).Text];
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',{name3, lifinfo(lifinfoindex)},'Icon','image.png','Tag',lifinfo(lifinfoindex).datatype);
                                        else
                                            cnode=cnode-1;
                                        end
                                    end
                                elseif ~strcmpi(name2,'iomanagerconfiguation') && ~strcmpi(name2,'environmentalgraph')
                                    lifinfo(lifinfoindex).filetype=app.filetype;
                                    lifinfo(lifinfoindex).LIFFile=app.file;
                                    lifinfo(lifinfoindex).name=name2;
                                    if isfield(thiselement2.Data,'Image') 
                                        lifinfo(lifinfoindex).datatype='Image';
                                        lifinfo(lifinfoindex).Image=thiselement2.Data.Image;
                                    else
                                        lifinfo(lifinfoindex).datatype='unknown';
                                    end
                                    lifinfo(lifinfoindex).Parent=[app.Nodes(cnode2).Parent.Text '_' app.Nodes(cnode2).Text];
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',{name2, lifinfo(lifinfoindex)},'Icon',cfGetimIcon(app,name2,cnode,lifinfo(lifinfoindex)),'Tag',lifinfo(lifinfoindex).datatype);
                                else
                                    cnode=cnode-1;
                                end
                            end
                        elseif ~strcmpi(name1,'iomanagerconfiguation') && ~strcmpi(name1,'environmentalgraph')
                            if isfield(thiselement1.Data,'Image') 
                                lifinfo(lifinfoindex).datatype='Image';
                                lifinfo(lifinfoindex).Image=thiselement1.Data.Image;
                            else
                                lifinfo(lifinfoindex).datatype='unknown';
                            end
                            lifinfo(lifinfoindex).Parent=app.Nodes(cnode1).Text;
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',{name1, lifinfo(lifinfoindex)},'Icon','image.png','Tag',lifinfo(lifinfoindex).datatype);
                        else
                            cnode=cnode-1;
                        end
                    end
                elseif ~strcmpi(name,'iomanagerconfiguation') && ~strcmpi(name,'environmentalgraph')
                    if isfield(thiselement.Data,'Image') 
                        lifinfo(lifinfoindex).datatype='Image';
                        lifinfo(lifinfoindex).Image=thiselement.Data.Image;
                    else
                        lifinfo(lifinfoindex).datatype='unknown';
                    end
                    lifinfo(lifinfoindex).Parent='';
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',{name, lifinfo(lifinfoindex)},'Icon','image.png','Tag',lifinfo(lifinfoindex).datatype);
                else
                    cnode=cnode-1;
                end
            end
            
            setLog(app,'Ready reading LIF file');
            app.Tree.Enable='on';
        end    
    
        function ReadLeicaXLEF(app)
            app.Tree.Enable='off';            
            xDoc = xmlread(app.file);
            app.Node = uitreenode(app.Tree,'Text',app.filename,'NodeData',fileparts(app.file), 'Icon','LAS-X.png','Tag','File');
            setLog(app,'Las-X XLEF File Opened');
            xmlInfo = xDoc.getElementsByTagName('Reference');
            cnode=0;
            setLog(app,[num2str(xmlInfo.getLength-1) ' reference nodes found']);
            for k = 0:xmlInfo.getLength-1
                cnode=cnode+1;
                thisInfo = xmlInfo.item(k);
                ref=getXLfile(app, char(thisInfo.getAttribute('File')),'');
                [~,name,ext]=fileparts(ref);
                lifinfo.name=name;
                lifinfo.XLEFFile=app.file;
                lifinfo.filetype=app.filetype;
                lifinfo.XLIFFile=ref;
                lifinfo.LIFFile=ref;
                lifinfo.LOFFile=getLOFfile(app, ref);
                [lifinfo.MemorySize, lifinfo.xmlElement]=cfReadLOFInfo(lifinfo);
                if strcmpi(ext,'.xlcf')
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',ref,'Icon','folder.png','Tag','Folder');
                    xDoc1 = xmlread(ref);
                    xmlInfo1 = xDoc1.getElementsByTagName('Reference');
                    cnode1=cnode;
                    for k1 = 0:xmlInfo1.getLength-1
                        cnode=cnode+1;
                        thisInfo1 = xmlInfo1.item(k1);
                        ref1=getXLfile(app, char(thisInfo1.getAttribute('File')),ref);
                        [~,name1,ext1]=fileparts(ref1);
                        lifinfo.name=name1;
                        lifinfo.XLEFFile=app.file;
                        lifinfo.filetype=app.filetype;
                        lifinfo.XLIFFile=ref1;
                        lifinfo.LIFFile=ref1;
                        lifinfo.LOFFile=getLOFfile(app, ref1);
                        [lifinfo.MemorySize, lifinfo.xmlElement]=cfReadLOFInfo(lifinfo);
                        if strcmpi(ext1,'.xlcf')
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',ref1,'Icon','folder.png','Tag','Folder');
                            xDoc2 = xmlread(ref1);
                            xmlInfo2 = xDoc2.getElementsByTagName('Reference');
                            cnode2=cnode;
                            for k2 = 0:xmlInfo2.getLength-1
                                cnode=cnode+1;
                                thisInfo2 = xmlInfo2.item(k2);
                                ref2=getXLfile(app, char(thisInfo2.getAttribute('File')),ref1);
                                [~,name2,ext2]=fileparts(ref2);
                                lifinfo.name=name2;
                                lifinfo.XLEFFile=app.file;
                                lifinfo.filetype=app.filetype;
                                lifinfo.XLIFFile=ref2;
                                lifinfo.LIFFile=ref2;
                                lifinfo.LOFFile=getLOFfile(app, ref2);
                                [lifinfo.MemorySize, lifinfo.xmlElement]=cfReadLOFInfo(lifinfo);
                                if strcmpi(ext2,'.xlcf')
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',ref2,'Icon','folder.png','Tag','Folder');
                                    xDoc3 = xmlread(ref2);
                                    xmlInfo3 = xDoc3.getElementsByTagName('Reference');
                                    cnode3=cnode;
                                    for k3 = 0:xmlInfo3.getLength-1
                                        cnode=cnode+1;
                                        thisInfo3 = xmlInfo3.item(k3);
                                        ref3=getXLfile(app, char(thisInfo3.getAttribute('File')),ref2);
                                        [~,name3,ext3]=fileparts(ref3);
                                        lifinfo.name=name3;
                                        lifinfo.XLEFFile=app.file;
                                        lifinfo.filetype=app.filetype;
                                        lifinfo.XLIFFile=ref3;
                                        lifinfo.LIFFile=ref3;
                                        lifinfo.LOFFile=getLOFfile(app, ref3);
                                        [lifinfo.MemorySize, lifinfo.xmlElement]=cfReadLOFInfo(lifinfo);
                                        if strcmpi(ext3,'.xlcf')
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',ref3,'Icon','folder.png','Tag','Folder');
                                        elseif ~strcmpi(name3,'iomanagerconfiguation') && ~strcmpi(name3,'environmentalgraph')
                                            s=xml2struct(lifinfo.XLIFFile);
                                            lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                                            lifinfo.datatype='Image';
                                            lifinfo.Parent=[app.Nodes(cnode3).Parent.Parent.Text '_' app.Nodes(cnode3).Parent.Text '_' app.Nodes(cnode3).Text];
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',{ref3,lifinfo},'Icon','image.png','Tag','Image');
                                        else
                                            cnode=cnode-1;
                                        end
                                    end
                                elseif ~strcmpi(name2,'iomanagerconfiguation') && ~strcmpi(name2,'environmentalgraph')
                                    s=xml2struct(lifinfo.XLIFFile);
                                    lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                                    lifinfo.datatype='Image';
                                    lifinfo.Parent=[app.Nodes(cnode2).Parent.Text '_' app.Nodes(cnode2).Text];
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',{ref2,lifinfo},'Icon','image.png','Tag','Image');
                                else
                                    cnode=cnode-1;
                                end
                            end
                        elseif ~strcmpi(name1,'iomanagerconfiguation') && ~strcmpi(name1,'environmentalgraph')
                            s=xml2struct(lifinfo.XLIFFile);
                            lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                            lifinfo.datatype='Image';
                            lifinfo.Parent=app.Nodes(cnode1).Text;
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',{ref1,lifinfo},'Icon','image.png','Tag','Image');
                        else
                            cnode=cnode-1;
                        end
                    end
                elseif ~strcmpi(name,'iomanagerconfiguation') && ~strcmpi(name,'environmentalgraph')
                    try
                        s=xml2struct(lifinfo.XLIFFile);
                    catch
                        s=SM_Xml2Struct(lifinfo.XLIFFile);
                    end
                    lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                    lifinfo.datatype='Image';
                    lifinfo.Parent='';
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',{ref,lifinfo},'Icon','image.png','Tag','Image');
                else
                    cnode=cnode-1;
                end
            end
            setLog(app,'Ready reading XLEF file');
            app.Tree.Enable='off';            
            %expand(app.Tree,'all');
        end
        
        function [XLfile]=getXLfile(app, sfile, sparent)
            %Java problem so used .NET not sfile=urldecode(replace(sfile,'+','%2b'));
            NET.addAssembly('System.Web');
            sfile=char(System.Web.HttpUtility.UrlDecode(replace(sfile,'+','%2b')));
            if ~isempty(sparent)
                i=strfind(sparent,'\');
                if contains(sfile,'..\')
                    sfile=strrep(sfile,'..\',[extractBefore(sparent,i(end-1)) '\']);
                else
                    sfile=strrep(sfile,'.\',[extractBefore(sparent,i(end)) '\']);
                end
            end
            sfile=strrep(sfile,'.\',app.filepath);
            XLfile=sfile;
        end
        
        function [LOFfile]=getLOFfile(~, sfile)
            [~, name, ~]=fileparts(sfile);
            i=strfind(sfile,'\');
            sfile=[extractBefore(sfile,i(end-1)) '\' name '.lof'];
            %MICA Bug solved?
            if ~isfile(sfile)
                sfile=replace(sfile,'\additional data\','\');
            end
            LOFfile=sfile;
        end

    end
    
    methods (Access = public)
        
        
        function setProgress(app, fractionComplete, sTitle)
            app.StatusPanel.Title=sTitle;
            app.Panel.Position = [0 0 2+(app.StatusPanel.Position(3)*fractionComplete-1) app.Panel.Position(4)];
            if fractionComplete==0
                setLog(app, sTitle);
            end
            if fractionComplete==1
                if strfind(sTitle,'Elapsed time')>0
                    t=sTitle(strfind(sTitle,'Elapsed time')+14:end);
                    setLog(app,['Elapsed time: ' t]);
                    app.timeSpend=app.timeSpend + cfHMS2Seconds(t);
                    setLog(app,['All time: ' cfSeconds2HMS(app.timeSpend)]);
                end
            end
            drawnow limitrate;
        end

        function setProgress2(app, fractionComplete, sTitle)
            app.ProgressBar.setProgress(fractionComplete, sTitle)
        end        
    
        function setLog(app, newlog)
            newlog=replace(newlog,'_','_ ');
            newlog=replace(newlog,'\','\ ');
            newlog=replace(newlog,'/','/ ');
            cutv=60;
            t=datetime;
            t.Format='HH:mm:ss';
            pspace=strfind(newlog,' ');
            tc=[char(t) '> '];
            if ~isempty(pspace)
                if pspace(1)==1
                    tc='                ';
                    cutv=60;
                end
            end
            newLine = [tc newlog];
            n=numel(app.LogBox.Items);
            newLine2='';
            if length(newLine)>cutv
                pspace=strfind(extractBefore(newLine,cutv),' ');
                if ~isempty(pspace); cutv=pspace(end); end
                if cutv>1; newLine2=extractAfter(newLine,cutv-1); end
                newLine=extractBefore(newLine,cutv);
            end
            if n<500
                app.LogBox.Items=[app.LogBox.Items(:); newLine];
            else
                app.LogBox.Items=[app.LogBox.Items(n-499:end) newLine];
            end
            app.LogBox.Value=newLine;
            scroll(app.LogBox,'bottom');
            if ~isempty(newLine2); setLog(app, newLine2); end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appDisable(app);
            app.Node.delete;
            app.host=getenv('COMPUTERNAME');
            app.CILIFSPlitterUIFigure.Name=['CI LIF Splitter ' app.Version];
            setLog(app,['Welcome to CI LIF Splitter ' app.Version])
            setLog(app,'Click on About for more info!')
            setProgress(app, 0,'Ready to Start')            
            appEnable(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.isCancelled=true;
            setLog(app,'Canceling..');
        end

        % Button pushed function: SelectFileLeica
        function SelectFileLeicaButtonPushed(app, event)
            [filenames,app.filepath]=uigetfile({'*.xlef;*.lif','Leica LAS-X Files'},'Select XLEF or LIF File',app.filepath,'MultiSelect','on');
            if app.filepath==0
                app.filepath='';
                return;
            end
            if ~iscell(filenames)
                app.filename=filenames;
                app.file=[app.filepath app.filename];
                [~,app.filebasename,app.filetype]=fileparts(app.file);
                if strcmpi(app.filetype,".xlef")    
                    ReadLeicaXLEF(app);
                end
                if strcmpi(app.filetype,".lif")
                    ReadLeicaLIF(app);
                end
            else
                for k=1:numel(filenames)
                    app.filename=filenames{k};
                    app.file=[app.filepath app.filename];
                    [~,app.filebasename,app.filetype]=fileparts(app.file);
                    if strcmpi(app.filetype,".xlef")    
                        ReadLeicaXLEF(app);
                    end
                    if strcmpi(app.filetype,".lif")
                        ReadLeicaLIF(app);
                    end                    
                end
            end
            app.Tree.Enable='on';
            appEnable(app);          
        end

        % Button pushed function: CloseSelectedFileLeica
        function CloseSelectedFileLeicaButtonPushed(app, event)
            tel=1;
            for i=1:numel(app.Tree.SelectedNodes)
                if numel(app.Tree.SelectedNodes)>0 
                    if strcmpi(app.Tree.SelectedNodes(tel).Tag,'File')
                        app.Tree.SelectedNodes(tel).delete
                    else
                        tel=tel+1;
                    end
                end
            end
            if numel(app.Tree.Children)==0
                appDisable(app);
                app.SelectFileLeica.Enable='on';
            end            
        end

        % Button pushed function: CButtonLIFSplit
        function CButtonLIFSplitPushed(app, event)
            appDisable(app);
            LIFsplit.cfProcessLIFSplit(app)
            appEnable(app);           
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if numel(selectedNodes)==1 && strcmpi(selectedNodes(1).Tag,'Image')
                lifinfo=selectedNodes(1).NodeData{2};
                [~, ~, iminfo]=cfReadMetaData(lifinfo);
                setLog(app,' ')
                setLog(app,lifinfo.name)
                if iminfo.isrgb 
                    setLog(app,'RGB Image')
                else
                    setLog(app,[num2str(iminfo.channels) ' Channels Image'])
                end
                if iminfo.tiles>1 && iminfo.zs==1
                    app.setLog(['Tiles: ' num2str(iminfo.tiles) ', TileSize: ' num2str(iminfo.xs) 'x' num2str(iminfo.ys) ' px, ' num2str(round(iminfo.xs*iminfo.xres2)) 'x' num2str(round(iminfo.ys*iminfo.xres2)) ' µm'])  
                elseif iminfo.zs>1 && iminfo.tiles<=1
                    app.setLog(['ImageSize: ' num2str(iminfo.xs) 'x' num2str(iminfo.ys) 'x' num2str(iminfo.zs) ' px, ' num2str(round(iminfo.xs*iminfo.xres2)) 'x' num2str(round(iminfo.ys*iminfo.xres2)) 'x' num2str(round(iminfo.zs*iminfo.zres2)) ' µm'])  
                elseif iminfo.tiles>1 && iminfo.zs>1
                    app.setLog(['Tiles: ' num2str(iminfo.tiles) ', TileSize: ' num2str(iminfo.xs) 'x' num2str(iminfo.ys) 'x' num2str(iminfo.zs) ' px, ' num2str(round(iminfo.xs*iminfo.xres2)) 'x' num2str(round(iminfo.ys*iminfo.xres2)) 'x' num2str(round(iminfo.zs*iminfo.zres2)) ' µm'])  
                else
                    app.setLog(['ImageSize: ' num2str(iminfo.xs) 'x' num2str(iminfo.ys) ' px, ' num2str(round(iminfo.xs*iminfo.xres2)) 'x' num2str(round(iminfo.ys*iminfo.xres2)) ' µm'])  
                end
                setLog(app,['SizeInBytes: ' cfBytesString(lifinfo.MemorySize)])
                setLog(app,['Objective: ' iminfo.objective]) 
                setLog(app,['Resolution: ' num2str(round(iminfo.xres2,3)) ' µm/pixel'])
            end
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            appEnable(app);
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            atitle='About';
            if isdeployed
                HTMLSource=[ctfroot '/CI_LIF_Splitter/' atitle '.html'];
            else
                HTMLSource=[pwd '\' atitle '.html'];
            end 
            amessage=fileread(HTMLSource);
            uialert(app.CILIFSPlitterUIFigure,amessage,atitle,'Icon','','Interpreter','html')            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CILIFSPlitterUIFigure = uifigure('Visible', 'off');
            app.CILIFSPlitterUIFigure.Name = 'CI LIF Splitter';
            app.CILIFSPlitterUIFigure.Icon = 'LIFSplittericon48x48.png';
            app.StatusPanel = uipanel(app.CILIFSPlitterUIFigure);
            app.StatusPanel.Title = 'Starting..... (please wait)';
            app.Panel = uipanel(app.StatusPanel);
            app.Tree = uitree(app.CILIFSPlitterUIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.NodeData = 'Root';
            app.Node.Text = 'Node';
            app.CancelButton = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = 'Cancel (2).png';
            app.CancelButton.Text = 'Cancel';
            app.CButtonLIFSplit = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.CButtonLIFSplit.ButtonPushedFcn = createCallbackFcn(app, @CButtonLIFSplitPushed, true);
            app.CButtonLIFSplit.Icon = 'images-stack.png';
            app.CButtonLIFSplit.Text = 'Split Selection to Separate LIF Files';
            app.SelectFileLeica = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.SelectFileLeica.ButtonPushedFcn = createCallbackFcn(app, @SelectFileLeicaButtonPushed, true);
            app.SelectFileLeica.Icon = 'plus2.png';
            app.SelectFileLeica.Text = 'Add Leica LAS-X File (LIF or XLEF) to Tree';
            app.CloseSelectedFileLeica = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.CloseSelectedFileLeica.ButtonPushedFcn = createCallbackFcn(app, @CloseSelectedFileLeicaButtonPushed, true);
            app.CloseSelectedFileLeica.Icon = 'min.png';
            app.CloseSelectedFileLeica.Text = 'Remove Selected';
            app.LogBox = uilistbox(app.CILIFSPlitterUIFigure);
            app.LogBox.Items = {};
            app.LogBox.Value = {};
            app.RButton = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.AboutButton = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.ProgressBar = wt.ProgressBar(app.CILIFSPlitterUIFigure);
            app.ConvertRGBImagestoQTIFFCheckBox = uicheckbox(app.CILIFSPlitterUIFigure);
            app.ConvertRGBImagestoQTIFFCheckBox.Text = 'Convert RGB Images to .QTIFF';
            % Show the figure after all components are created
            app.CILIFSPlitterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CI_LIF_Splitter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CILIFSPlitterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CILIFSPlitterUIFigure)
        end
    end
end